classdef TableDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BloodPressureAnalyisisUIFigure  matlab.ui.Figure
        EditField_5                     matlab.ui.control.EditField
        StateLamp                       matlab.ui.control.Lamp
        MenuPanel                       matlab.ui.container.Panel
        LoadSSPButton                   matlab.ui.control.Button
        SoundSpeedProfilemsTextArea     matlab.ui.control.TextArea
        SoundSpeednProfileLabel         matlab.ui.control.Label
        ReceiverDepthTextArea           matlab.ui.control.TextArea
        ReceiverDepthTextAreaLabel      matlab.ui.control.Label
        SelectModelDropDown             matlab.ui.control.DropDown
        SelectModelDropDownLabel        matlab.ui.control.Label
        NumofReceiverEditField          matlab.ui.control.EditField
        NumofReceiverEditFieldLabel     matlab.ui.control.Label
        TopoptionEditField              matlab.ui.control.EditField
        TopoptionEditFieldLabel         matlab.ui.control.Label
        RUNSaveButton                   matlab.ui.control.Button
        LoadTableButton                 matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        EditField_1                     matlab.ui.control.EditField
        SaveTableButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
    end

    methods (Access = private)

        function updateplot(app)
            % Get Table UI component data
            %             t = app.UITable.DisplayData;
            %
            %             % Plot modified data
            %             x2 = t.Age;
            %             y2 = t.BloodPressure(:,2);
            %             plot(app.UIAxes2,x2,y2,'-o');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file

            % Set the path where this file is located to the current workspace path
            filep = mfilename('fullpath');            % filep contains the path where this m-file is already located and the file name (without the .m suffix)
            [pathstr,~] = fileparts(filep);             % pathstr is the path where this m file is located
            cd(pathstr);                                       % Change the current Active Directory path

            t = readtable('.\1.1.0_SettingTables\Parameters.xlsx', 'VariableNamingRule','preserve');

            app.UITable.Data = t;

            app.EditField_1.Value = 'A.TrainingSet simulation: 0%';
            app.EditField_2.Value = 'B.TestSet simulation: 0%';
            % app.EditField_3.Value = 'C.TestSet simulation: 0%';
            % app.EditField_4.Value = 'D.MeasuredSet simulation: 0%';
        end

        % Callback function: UITable, UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            % updateplot(app);
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            % Push button to save setting table
            [file,path] =uiputfile('*.xlsx','Saving File','test.xlsx');         % Save file path
            if isequal(file, 0) || isequal(path, 0)                                   % Cancel
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),...
                    ' and then clicked Save.'])
                writetable(app.UITable.Data, [path '/' file]);                 % Write as xlsx file
            end

        end

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            % Push button to load setting table
            [file,path] =uigetfile('*.xlsx','Loading File','test.xlsx');                        % Loading file path
            if isequal(file, 0) || isequal(path, 0)                                                     % Cancel
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),...
                    ' .'])
                t = readtable( [path '/' file], 'VariableNamingRule','preserve');      % Read xlsx file
                app.UITable.Data = t;
            end

        end

        % Button pushed function: RUNSaveButton
        function RUNSaveButtonPushed(app, event)
            % Set the path where this mlapp file is located to the current workspace path
            filep = mfilename('fullpath');
            [pathstr,~]=fileparts(filep);
            cd(pathstr);

            % Runing state lamp
            app.StateLamp.Color = 'g';
            app.EditField_5.Value = 'Running';
            app.EditField_5.FontColor = 'g';
            table1 = app.UITable.Data;

            try
                % menu setting
                % Select model
                menu.select_model = app.SelectModelDropDown.Value;                  % Select sound propagation model KRAKEN BELLHOP
                menu.top_option = app.TopoptionEditField.Value;                             % Top option
                menu.Nrd = str2double(app.NumofReceiverEditField.Value);             % Number of receivers
                rd_str = app.ReceiverDepthTextArea.Value{1};
                % Setting rd
                C = sscanf(rd_str,'%f');
                if menu.Nrd == 1 && length(C)==1
                    menu.rd = C(1);                                                                                 % Receivers depth
                else if menu.Nrd > 1 && length(C)==2
                        menu.rd = linspace(C(1), C(2), menu.Nrd);
                else if menu.Nrd == length(C)
                        menu.rd = C;
                else
                    % f = msgbox("NumofReceiver is not equal with ReceiverDepth array.","Error","error");
                    error("NumofReceiver is not equal with ReceiverDepth array.")
                end
                end
                end
                % Read ssp
                menu.ssp = zeros(length(app.SoundSpeedProfilemsTextArea.Value), 2);
                for ic = 1: length(app.SoundSpeedProfilemsTextArea.Value)
                    ssp_str = app.SoundSpeedProfilemsTextArea.Value{ic};
                    menu.ssp(ic,:) = sscanf(ssp_str,'%f %f/n');                                         % User-defined sound speed profile
                end
                handles = [app.EditField_1; app.EditField_2];

                % Data generation
                FunSWDataSim(table1, menu, app.UIAxes1,  app.UIAxes2, handles);
            catch ME               
                % Error state lamp
                app.StateLamp.Color = 'red';
                app.EditField_5.Value = 'Error';
                app.EditField_5.FontColor = 'red';
                rethrow(ME)
            end
            if ~strcmp(app.EditField_5.Value, 'Error')
                % Completed state lamp
                app.StateLamp.Color = [128,128,128]/256;
                app.EditField_5.Value = 'Completed';
                app.EditField_5.FontColor = [128,128,128]/256;
            end
        end

        % Button pushed function: LoadSSPButton
        function LoadSSPButtonPushed(app, event)
            [file,path] =uigetfile('*.txt','Loading File','test.txt');
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),...
                    ' .'])
                t = load( [path '/' file]);
                for ic = 1: length(t)
                    app.SoundSpeedProfilemsTextArea.Value{ic} = sprintf('%.1f %.1f', t(ic,1), t(ic,2));
                    %                 app.sound_speed_of_water = t;
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BloodPressureAnalyisisUIFigure = uifigure('Visible', 'off');
            app.BloodPressureAnalyisisUIFigure.Name = 'Blood Pressure Analyisis';
            app.UIAxes2 = uiaxes(app.BloodPressureAnalyisisUIFigure);
            title(app.UIAxes2, 'Data')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes1 = uiaxes(app.BloodPressureAnalyisisUIFigure);
            title(app.UIAxes1, 'Data')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UITable = uitable(app.BloodPressureAnalyisisUIFigure);
            app.UITable.ColumnName = {'Parameters'; 'Units'; 'Lower bound'; 'Upper bound'; 'No. of discrete values'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false];
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.SaveTableButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            app.EditField_1 = uieditfield(app.BloodPressureAnalyisisUIFigure, 'text');
            app.EditField_2 = uieditfield(app.BloodPressureAnalyisisUIFigure, 'text');
            app.LoadTableButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = 'Load Table';
            app.RUNSaveButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.RUNSaveButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSaveButtonPushed, true);
            app.RUNSaveButton.Text = 'RUN & Save';
            app.MenuPanel = uipanel(app.BloodPressureAnalyisisUIFigure);
            app.MenuPanel.Title = 'Menu';
            app.TopoptionEditFieldLabel = uilabel(app.MenuPanel);
            app.TopoptionEditFieldLabel.Text = 'Top option';
            app.TopoptionEditField = uieditfield(app.MenuPanel, 'text');
            app.TopoptionEditField.Value = 'CVW';
            app.NumofReceiverEditFieldLabel = uilabel(app.MenuPanel);
            app.NumofReceiverEditFieldLabel.Text = 'Num of Receiver';
            app.NumofReceiverEditField = uieditfield(app.MenuPanel, 'text');
            app.NumofReceiverEditField.Value = '6';
            app.SelectModelDropDownLabel = uilabel(app.MenuPanel);
            app.SelectModelDropDownLabel.Text = 'Select Model';
            app.SelectModelDropDown = uidropdown(app.MenuPanel);
            app.SelectModelDropDown.Items = {'KRAKEN', 'BELLHOP', 'RAM'};
            app.SelectModelDropDown.Value = 'KRAKEN';
            app.ReceiverDepthTextAreaLabel = uilabel(app.MenuPanel);
            app.ReceiverDepthTextAreaLabel.Text = 'Receiver Depth';
            app.ReceiverDepthTextArea = uitextarea(app.MenuPanel);
            app.ReceiverDepthTextArea.Value = {'10 20 30 40 50 60'};
            app.SoundSpeednProfileLabel = uilabel(app.MenuPanel);
            app.SoundSpeednProfileLabel.Text = {' Sound Speed '; ' Profile (m/s)'};
            app.SoundSpeedProfilemsTextArea = uitextarea(app.MenuPanel);
            app.SoundSpeedProfilemsTextArea.Value = {'    0.0  1548.52  '; '  200.0  1530.29  '; '  250.0  1526.69  '; '  400.0  1517.78  '; '  600.0  1509.49  '; '  800.0  1504.30  '; ' 1000.0  1501.38  '; ' 1200.0  1500.14  '; ' 1400.0  1500.12  '; ' 1600.0  1501.02  '; ' 1800.0  1502.57  '; ' 2000.0  1504.62  '; ' 2200.0  1507.02  '; ' 2400.0  1509.69  '; ' 2600.0  1512.55  '; ' 2800.0  1515.56  '; ' 3000.0  1518.67  '; ' 3200.0  1521.85  '; ' 3400.0  1525.10  '; ' 3600.0  1528.38  '; ' 3800.0  1531.70  '; ' 4000.0  1535.04  '; ' 4200.0  1538.39  '; ' 4400.0  1541.76  '; ' 4600.0  1545.14  '; ' 4800.0  1548.52  '; ' 5000.0  1551.91  '};
            app.LoadSSPButton = uibutton(app.MenuPanel, 'push');
            app.LoadSSPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSSPButtonPushed, true);
            app.LoadSSPButton.Text = 'Load SSP';
            app.StateLamp = uilamp(app.BloodPressureAnalyisisUIFigure);
            app.EditField_5 = uieditfield(app.BloodPressureAnalyisisUIFigure, 'text');
            % Show the figure after all components are created
            app.BloodPressureAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableDataApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BloodPressureAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BloodPressureAnalyisisUIFigure)
        end
    end
end